classdef Pressure_displayer_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        DgraphDropDown                  matlab.ui.control.DropDown
        DgraphDropDownLabel             matlab.ui.control.Label
        DisplayvalueDropDown            matlab.ui.control.DropDown
        DisplayvalueLabel               matlab.ui.control.Label
        generatinganddisplayingaccousticpressureandphasesLabel  matlab.ui.control.Label
        GenerateDataButton              matlab.ui.control.Button
        DisplayDataButton               matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        DatastatusLabel                 matlab.ui.control.Label
        ExportpressureDataButton        matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        VisualisemodelCheckBox          matlab.ui.control.CheckBox
        DirectivitypatternButtonGroup   matlab.ui.container.ButtonGroup
        CPRBButton                      matlab.ui.control.RadioButton
        OMNIButton                      matlab.ui.control.RadioButton
        RestoredefaultsettingsButton    matlab.ui.control.Button
        ResolutionnumberofpointsgeneratedLabel  matlab.ui.control.Label
        ZaxisSlider                     matlab.ui.control.Slider
        ZaxisSliderLabel                matlab.ui.control.Label
        YaxisSlider                     matlab.ui.control.Slider
        YaxisSliderLabel                matlab.ui.control.Label
        XaxisSlider                     matlab.ui.control.Slider
        XaxisSliderLabel                matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        FocuspointcoordinatesDropDown   matlab.ui.control.DropDown
        FocuspointcoordinatesDropDownLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        speakersspreadYEditField        matlab.ui.control.NumericEditField
        numberofspeakersYEditField      matlab.ui.control.NumericEditField
        additionalvisibilitybordermEditField  matlab.ui.control.NumericEditField
        additionalvisibilityborderLabel  matlab.ui.control.Label
        topbottomdistmEditField         matlab.ui.control.NumericEditField
        topbottomdistancemLabel         matlab.ui.control.Label
        signalamplitudeEditField        matlab.ui.control.NumericEditField
        signalamplitudeEditFieldLabel   matlab.ui.control.Label
        signalfrequencyHzEditField      matlab.ui.control.NumericEditField
        signalfrequencyHzLabel          matlab.ui.control.Label
        speakersspreadXEditField        matlab.ui.control.NumericEditField
        speakersspreadmLabel            matlab.ui.control.Label
        speakerradiusmEditField         matlab.ui.control.NumericEditField
        speakerradiusmLabel             matlab.ui.control.Label
        numberofspeakersXEditField      matlab.ui.control.NumericEditField
        numberofspeakersEditFieldLabel  matlab.ui.control.Label
        setparametersofmodeltodesiredvaluesLabel  matlab.ui.control.Label
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        DataplotsTab                    matlab.ui.container.Tab
        AccousticpressureLabel          matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        dimentionalaccousticpressuredisplaypresentingLabel  matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_10                       matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        speakerCoordinatesX 
        speakerCoordinatesY % vectors with location of speakers centers
        focusPointX
        focusPointY
        focusPointZ % coordinates of point of focus of speakers waves
        PressureValueVectorX
        PressureValueVectorY
        PressureValueVectorZ % vectors creating grid, sizes appropriate for val array
        phaseArrayBottom % phases of top speakers
        phaseArrayTop % phases of bottom speakers
        ValArray % array of pressure data ready to be plotted
        dataReady % boolean used to properly handle displaying data
    end

    
    methods (Access = private)
      
        function CountSpeakerCoordinates(app)
            r = app.speakerradiusmEditField.Value;
            spreadX = app.speakersspreadXEditField.Value;
            spreadY = app.speakersspreadYEditField.Value;
            nX = app.numberofspeakersXEditField.Value;
            nY = app.numberofspeakersYEditField.Value;

            app.speakerCoordinatesX = (0:(2*r+spreadX):(nX-1)*(2*r+spreadX));
            app.speakerCoordinatesY = (0:(2*r+spreadY):(nY-1)*(2*r+spreadY));
        end
        
        function [xVec,yVec,zVec] = MakeMeshgridBase(app)
            r = app.speakerradiusmEditField.Value;
            Xmin = app.speakerCoordinatesX(1);
            Xmax = app.speakerCoordinatesX(end);
            Xstep = app.XaxisSlider.Value;
            Ymin = app.speakerCoordinatesY(1);
            Ymax = app.speakerCoordinatesY(end);
            Ystep = app.YaxisSlider.Value;
            height = app.topbottomdistmEditField.Value;
            Zstep = app.ZaxisSlider.Value;
            border = app.additionalvisibilitybordermEditField.Value;
            
            xVec = linspace(Xmin-border-r,Xmax+border+r,Xstep);
            yVec = linspace(Ymin-border-r,Ymax+border+r,Ystep);
            zVec = linspace(0,height,Zstep); 
        end

        function phaseArray = CountPhaseArray(app,height)
            f = app.signalfrequencyHzEditField.Value;
            omega = 2*pi*f;
            c = 343;
            [Sx, Sy] = meshgrid(app.speakerCoordinatesX, app.speakerCoordinatesY);
            focusR = sqrt((Sx - app.focusPointX).^2 + (Sy - app.focusPointY).^2 + (height - app.focusPointZ).^2);

            % 0 : 360 deg range:
            % phiArray = omega * rem(focusR, (c/f)) / c; 

            % -180 : 180 deg range:
            phaseArray = mod((omega * focusR / c) + pi, 2*pi) - pi;
        end
        
        function CountPressureData(app,phi0bottom,phi0top)
            k = 2*pi*app.signalfrequencyHzEditField.Value / 343;
            A = app.signalamplitudeEditField.Value;
            a = app.speakerradiusmEditField.Value;
            [Xg, Yg, Zg] = meshgrid(app.PressureValueVectorX, app.PressureValueVectorY, app.PressureValueVectorZ);
            app.ValArray = zeros(size(Xg)); 
        
            [Sx, Sy] = meshgrid(app.speakerCoordinatesX, app.speakerCoordinatesY);
            
            for i = 1:numel(Sx)
                dXY = sqrt((Xg - Sx(i)).^2 + (Yg - Sy(i)).^2);
                R_Bot = sqrt(dXY.^2 + Zg.^2);
                R_Top = sqrt(dXY.^2 + (Zg - app.topbottomdistmEditField.Value).^2);
                if strcmp(app.DirectivitypatternButtonGroup.SelectedObject.Text, 'CPRB') 
                    argBottom = k * a * (dXY ./ R_Bot) + eps;
                    argTop = k * a * (dXY ./ R_Top) + eps;
                    disp('calculating for CPRB')
                    D_Bot = 2 * besselj(1, argBottom) ./ argBottom;
                    D_Top = 2 * besselj(1, argTop) ./ argTop;
                elseif strcmp(app.DirectivitypatternButtonGroup.SelectedObject.Text, 'OMNI')
                    D_Bot = 1;
                    D_Top = 1;
                    disp('calculating for OMNI')
                end
                app.ValArray = app.ValArray + ...
                    (A .* D_Bot ./ R_Bot .* cos(k*R_Bot - app.phaseArrayBottom(i) + phi0bottom)) + ...
                    (A .* D_Top ./ R_Top .* cos(k*R_Top - app.phaseArrayTop(i) + phi0top));
            end
        end

        function CountFocusPointCoordinates(app)
            if(app.FocuspointcoordinatesDropDown.Value == "m")
                xFoc = app.XEditField.Value;
                yFoc = app.YEditField.Value;
                zFoc = app.ZEditField.Value;
            elseif(app.FocuspointcoordinatesDropDown.Value == "%")
                xFoc = (app.speakerCoordinatesX(end))*app.XEditField.Value/100;
                yFoc = (app.speakerCoordinatesY(end))*app.YEditField.Value/100;
                zFoc = app.topbottomdistmEditField.Value*app.ZEditField.Value/100;
            end         
            app.focusPointX = xFoc;
            app.focusPointY = yFoc;
            app.focusPointZ = zFoc;
        end
                
        function ChangeFocusPointEditFieldsUnit(app)
            previousX = app.XEditField.Value;
            previousY = app.YEditField.Value;
            previousZ = app.ZEditField.Value;

            if (app.FocuspointcoordinatesDropDown.Value == "%")
                app.XEditField.Value = previousX/app.speakerCoordinatesX(end)*100;
                app.YEditField.Value = previousY/app.speakerCoordinatesY(end)*100;
                app.ZEditField.Value = previousZ/app.topbottomdistmEditField.Value*100;
            elseif(app.FocuspointcoordinatesDropDown.Value == "m")
                app.XEditField.Value = app.speakerCoordinatesX(end)*previousX/100;
                app.YEditField.Value = app.speakerCoordinatesY(end)*previousY/100;
                app.ZEditField.Value = app.topbottomdistmEditField.Value*previousZ/100;
            end            
        end
        
        function TryUpdateModelSchemePlot(app)
            cla(app.UIAxes_3,"reset");
            hold(app.UIAxes_3,"on");
            if(app.VisualisemodelCheckBox.Value == true)
                app.PlotTransducerDistribution3D
                app.PlotFocusPoint3D;
            end
            hold(app.UIAxes_3,"off");
            axis(app.UIAxes_3,"equal");
            grid(app.UIAxes_3,"on");
            title(app.UIAxes_3,"Model scheme","FontSize",11);
            xlabel(app.UIAxes_3,"X");
            ylabel(app.UIAxes_3,"Y");
            zlabel(app.UIAxes_3,"Z");
            view(app.UIAxes_3,120,30);              
        end
        
        function TryUpdateTransducerDirectivityPlot(app)
            cla(app.UIAxes_11);
            % hold(app.UIAxes_11,"on");
            if(app.VisualisemodelCheckBox.Value == true)
                app.PlotTransducerDirectivity2D
            end
        end
        
        function PlotFocusPoint3D(app)
            plot3(app.UIAxes_3,app.focusPointX,app.focusPointY,app.focusPointZ,"o","MarkerSize",5,"Color",'b');
            r = app.speakerradiusmEditField.Value;
            xUnit1(1:2) = app.focusPointX;
            yUnit1(1:2) = app.focusPointY;
            zUnit1(1:2) = app.focusPointZ;
            xUnit2 = linspace(-r,app.focusPointX,2);
            yUnit2 = linspace(-r,app.focusPointY,2);
            zUnit2 = linspace(0,app.focusPointZ,2);
            plot3(app.UIAxes_3,xUnit2,yUnit1,zUnit1,xUnit1,yUnit2,zUnit1,xUnit1,yUnit1,zUnit2,"Color",'b',"LineWidth",0.5)
        end

        function PlotTransducerDirectivity2D(app)
            theta = linspace(-pi/2, pi/2, 200); 
            
            if strcmp(app.DirectivitypatternButtonGroup.SelectedObject.Text, 'CPRB')
                k = 2*pi*app.signalfrequencyHzEditField.Value / 343;
                a = app.speakerradiusmEditField.Value;
                arg = k * a * sin(theta) + eps;
                D = abs(2 * besselj(1, arg) ./ arg);
            elseif strcmp(app.DirectivitypatternButtonGroup.SelectedObject.Text, 'OMNI')
                D = ones(size(theta)); 
            end
            
            x = D .* sin(theta);
            y = D .* cos(theta);
            
            ax = app.UIAxes_11;
            cla(ax);
            
            plot(ax, x, y, 'b-', 'LineWidth', 2);
            hold(ax, 'on');
            plot(ax, 0, 0, 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 5);
            hold(ax, 'off');
            
            axis(ax, 'equal'); grid(ax, 'on'); title(ax, 'Transducer directivity');
            xlim(ax, [-1.1, 1.1]); ylim(ax, [0, 1.1]);
        end
        
        function PlotTransducerDistribution3D(app)
            r = app.speakerradiusmEditField.Value;
            fi = 0:2*pi/10:2*pi;
            nX = app.numberofspeakersXEditField.Value;
            yUnit = r*sin(fi)+app.speakerCoordinatesY(:);
            zUnitBottom(1:length(fi)) = 0;
            zUnitTop(1:length(fi)) = app.topbottomdistmEditField.Value;
            
            for ix = 1:nX
                xUnit = r*cos(fi)+app.speakerCoordinatesX(ix);
                plot3(app.UIAxes_3, xUnit,yUnit,zUnitBottom,"Color",'r',LineWidth=0.7);
                plot3(app.UIAxes_3, xUnit,yUnit,zUnitTop,"Color",'r',LineWidth=0.7);
            end
        end
             
        function PlotPhaseArrays2D(app, phiArrayBottom, phiArrayTop)
            zTop = rem(rad2deg(phiArrayTop), 360);
            zBot = rem(rad2deg(phiArrayBottom), 360);
            
            configs = {
                app.UIAxes,   "Phases of top array",    zTop;
                app.UIAxes_9, "Phases of bottom array", zBot
            };
            
            for i = 1:2
                ax = configs{i,1};
                data = configs{1,3};                
                % Y axis flip for desired display
                ax.YDir = 'normal';

                hold(ax, "on"); 
                imagesc(ax, data);
                ax.YDir = 'normal';
                [rows, cols] = size(data);
                % black grid
                xline(ax, 0.5:1:cols+0.5, 'k-', 'LineWidth', 1);
                yline(ax, 0.5:1:rows+0.5, 'k-', 'LineWidth', 1);
                hold(ax, "off");
                
                colormap(ax, 'parula');
                clim(ax, [-180, 180]);
                xlim(ax, [0.5, cols+0.5]);
                ylim(ax, [0.5, rows+0.5]);
                cb = colorbar(ax, 'eastoutside');
                cb.Ticks = -180:90:180; 
                
                title(ax, configs{i,2}, 'FontSize', 11, 'fontweight', 'bold');
                xlabel(ax, "X");
                ylabel(ax, "Y");
            end
        end

        % functionality of changing resolution, also requires adjusting.
        % Error may occur, of which reason is probably either 
        % MakeMeshgridBase method, or CountPressureLevelData method, 
        % missinteracting with Plot functions below.

        function PlotDataSlices3D(app)
            cla(app.UIAxes_6,"reset");
            [Xg, Yg, Zg] = meshgrid(app.PressureValueVectorX, app.PressureValueVectorY, app.PressureValueVectorZ);
            graph = slice(app.UIAxes_6, Xg, Yg, Zg, app.ValArray, ...
                app.focusPointX, app.focusPointY, app.focusPointZ);
            set(graph,'EdgeColor','none',...
                       'FaceColor','interp',...
                       'FaceAlpha','interp')
            alpha(app.UIAxes_6,"color");
            title(app.UIAxes_6,"accoustic pressure slices","FontSize",11,'fontweight','bold');
            xlabel(app.UIAxes_6,"X");
            ylabel(app.UIAxes_6,"Y");
            zlabel(app.UIAxes_6,"Z");
            view(app.UIAxes_6,120,30);
        end
        
        function PlotDataSlices2D(app)
            [X, Y, Z] = meshgrid(app.PressureValueVectorX, app.PressureValueVectorY, app.PressureValueVectorZ);
            
            if strcmp(app.DisplayvalueDropDown.Value, 'Acoustic pressure')
                V = abs(app.ValArray); 
            elseif strcmp(app.DisplayvalueDropDown.Value, 'Gorkov')
                P_sq = abs(app.ValArray).^2;
                [Fx, Fy, Fz] = gradient(P_sq);
                V = sqrt(Fx.^2 + Fy.^2 + Fz.^2);
            end

            cX = app.focusPointX; 
            cY = app.focusPointY; 
            cZ = app.focusPointZ;
        
            configs = {
                app.UIAxes_2,  90, 0,  "Side View (X)", 1; 
                app.UIAxes_7,  0,  90, "Top View (Z)",  2;
                app.UIAxes_10, 0,  0,  "Side View (Y)", 3
            };

            globalMaxAmp = 0; % for scaling the slices colorbar

            for i = 1:3
                ax = configs{i,1};
                mode = configs{i,5};
                cla(ax, "reset");
                
                if mode == 1 
                    h = slice(ax, X, Y, Z, V, cX, [], []);
                    ylabel(ax, "Y"); zlabel(ax, "Z");
                elseif mode == 2
                    h = slice(ax, X, Y, Z, V, [], [], cZ);
                    xlabel(ax, "X"); ylabel(ax, "Y");
                elseif mode == 3
                    h = slice(ax, X, Y, Z, V, [], cY, []);
                    xlabel(ax, "X"); zlabel(ax, "Z");
                end
        
                shading(ax, 'interp');
                colormap(ax, 'jet');
                view(ax, configs{i,2}, configs{i,3});
                title(ax, configs{i,4});
                axis(ax, 'tight');

                % add colorbal to last slice
                if mode == 2, colorbar(ax, 'eastoutside'); end

                currentSliceData = get(h, 'CData');
                currentMaxAmp = max(currentSliceData(:), [], 'omitnan');
                if currentMaxAmp > globalMaxAmp 
                    globalMaxAmp = currentMaxAmp; 
                end
            end

            clim(app.UIAxes_2, [0, globalMaxAmp]);
            clim(app.UIAxes_7, [0, globalMaxAmp]);
            clim(app.UIAxes_10, [0, globalMaxAmp]);
        end
        
        function PlotDataVolume3D(app)
            cla(app.UIAxes_5,"reset");
            data = smooth3(app.ValArray);
            patch(app.UIAxes_5,isocaps(data,.5),...
                'FaceColor','interp','EdgeColor','none');
            p1 = patch(app.UIAxes_5,isosurface(data,.5),...
                'FaceColor','blue','EdgeColor','none');
            isonormals(data,p1);
            view(app.UIAxes_5,3); 
            axis(app.UIAxes_5,'vis3d', 'tight') ;
            camlight(app.UIAxes_5,'left') ;
            lighting(app.UIAxes_5, 'gouraud');
            alpha(app.UIAxes_5,'color');
            alpha(app.UIAxes_5,'scaled');
            title(app.UIAxes_5,"Acoustic pressure 3D","FontSize",11,'fontweight','bold');
            xlabel(app.UIAxes_5,"X");
            ylabel(app.UIAxes_5,"Y");
            zlabel(app.UIAxes_5,"Z");     
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CountSpeakerCoordinates;
            app.CountFocusPointCoordinates;
        end

        % Value changed function: numberofspeakersXEditField
        function numberofspeakersXEditFieldValueChanged(app, event)
            app.CountSpeakerCoordinates;
            app.TryUpdateModelSchemePlot;
        end

        % Value changed function: numberofspeakersYEditField
        function numberofspeakersYEditFieldValueChanged(app, event)
            app.CountSpeakerCoordinates;
            app.TryUpdateModelSchemePlot;
        end

        % Value changed function: speakerradiusmEditField
        function speakerradiusmEditFieldValueChanged(app, event)
            app.CountSpeakerCoordinates;
            app.TryUpdateModelSchemePlot;
        end

        % Value changed function: topbottomdistmEditField
        function topbottomdistmEditFieldValueChanged(app, event)
            app.CountSpeakerCoordinates;
            app.TryUpdateModelSchemePlot;  
        end

        % Value changed function: speakersspreadXEditField
        function speakersspreadXEditFieldValueChanged(app, event)
            app.CountSpeakerCoordinates;
            app.TryUpdateModelSchemePlot;       
        end

        % Value changed function: speakersspreadYEditField
        function speakersspreadYEditFieldValueChanged(app, event)
            app.CountSpeakerCoordinates;
            app.TryUpdateModelSchemePlot;       
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.CountFocusPointCoordinates;
            app.TryUpdateModelSchemePlot;  
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.CountFocusPointCoordinates;
            app.TryUpdateModelSchemePlot;
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            app.CountFocusPointCoordinates;
            app.TryUpdateModelSchemePlot;     
        end

        % Value changed function: FocuspointcoordinatesDropDown
        function FocuspointcoordinatesDropDownValueChanged(app, event)
            app.CountSpeakerCoordinates;
            app.ChangeFocusPointEditFieldsUnit;
            app.CountFocusPointCoordinates;
        end

        % Button pushed function: GenerateDataButton
        function GenerateDataButtonPushed(app, event)
            app.TextArea.Value = "Generating data...";
            app.phaseArrayBottom = app.CountPhaseArray(0);
            app.phaseArrayTop = app.CountPhaseArray(app.topbottomdistmEditField.Value);
            [app.PressureValueVectorX,app.PressureValueVectorY,app.PressureValueVectorZ] = app.MakeMeshgridBase;
            app.CountPressureData(0,pi); % change to (0,0) to obtain maximum amplitude at focus point
            app.dataReady = true;
            app.TextArea.Value = "Data generated successfully.";
        end

        % Button pushed function: DisplayDataButton
        function DisplayDataButtonPushed(app, event)
            if(app.dataReady)
                app.TextArea.Value = "Displaying data";
                app.PlotPhaseArrays2D(app.phaseArrayBottom,app.phaseArrayTop); 
                %app.PlotPressureSlices3D;
                app.PlotDataSlices2D;
                app.PlotDataVolume3D;
                app.TextArea.Value = "Data displayed successfully";
            else
                app.TextArea.Value = "There is nothing to display. Generate data first, in order to display it";
            end
        end

        % Value changed function: XaxisSlider
        function XaxisSliderValueChanged(app, event)
            app.YaxisSlider.Value = app.XaxisSlider.Value;           
        end

        % Value changed function: YaxisSlider
        function YaxisSliderValueChanged(app, event)
            app.XaxisSlider.Value= app.YaxisSlider.Value;            
        end

        % Selection changed function: DirectivitypatternButtonGroup
        function DirectivitypatternButtonGroupSelectionChanged(app, event)
            app.TryUpdateTransducerDirectivityPlot;
        end

        % Value changed function: VisualisemodelCheckBox
        function VisualisemodelCheckBoxValueChanged(app, event)
            app.TryUpdateModelSchemePlot;
            app.TryUpdateTransducerDirectivityPlot;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {672, 672};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {225, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExportpressureDataButton = uibutton(app.LeftPanel, 'push');
            app.ExportpressureDataButton.Text = 'Export pressure Data';
            app.DatastatusLabel = uilabel(app.LeftPanel);
            app.DatastatusLabel.Text = 'Data status:';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'No data generated'};
            app.DisplayDataButton = uibutton(app.LeftPanel, 'push');
            app.DisplayDataButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayDataButtonPushed, true);
            app.DisplayDataButton.Text = 'Display Data';
            app.GenerateDataButton = uibutton(app.LeftPanel, 'push');
            app.GenerateDataButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDataButtonPushed, true);
            app.GenerateDataButton.Text = 'Generate Data';
            app.generatinganddisplayingaccousticpressureandphasesLabel = uilabel(app.LeftPanel);
            app.generatinganddisplayingaccousticpressureandphasesLabel.Text = 'Generating and displaying data:';
            app.DisplayvalueLabel = uilabel(app.LeftPanel);
            app.DisplayvalueLabel.Text = 'Display value:';
            app.DisplayvalueDropDown = uidropdown(app.LeftPanel);
            app.DisplayvalueDropDown.Items = {'Acoustic pressure', 'Gorkov'};
            app.DisplayvalueDropDown.Value = 'Acoustic pressure';
            app.DgraphDropDownLabel = uilabel(app.LeftPanel);
            app.DgraphDropDownLabel.Text = '3D graph:';
            app.DgraphDropDown = uidropdown(app.LeftPanel);
            app.DgraphDropDown.Items = {'3D Smooth Graph', 'Slices'};
            app.DgraphDropDown.Value = '3D Smooth Graph';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.UIAxes_3 = uiaxes(app.SettingsTab);
            title(app.UIAxes_3, 'Model scheme')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_11 = uiaxes(app.SettingsTab);
            title(app.UIAxes_11, 'Transducer directivity')
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
            app.setparametersofmodeltodesiredvaluesLabel = uilabel(app.SettingsTab);
            app.setparametersofmodeltodesiredvaluesLabel.Text = 'set parameters of model to desired values';
            app.numberofspeakersEditFieldLabel = uilabel(app.SettingsTab);
            app.numberofspeakersEditFieldLabel.Text = 'number of speakers';
            app.numberofspeakersXEditField = uieditfield(app.SettingsTab, 'numeric');
            app.numberofspeakersXEditField.Limits = [1 100];
            app.numberofspeakersXEditField.RoundFractionalValues = 'on';
            app.numberofspeakersXEditField.ValueDisplayFormat = '%.0f';
            app.numberofspeakersXEditField.ValueChangedFcn = createCallbackFcn(app, @numberofspeakersXEditFieldValueChanged, true);
            app.numberofspeakersXEditField.Value = 10;
            app.speakerradiusmLabel = uilabel(app.SettingsTab);
            app.speakerradiusmLabel.Text = 'speaker radius, m';
            app.speakerradiusmEditField = uieditfield(app.SettingsTab, 'numeric');
            app.speakerradiusmEditField.ValueChangedFcn = createCallbackFcn(app, @speakerradiusmEditFieldValueChanged, true);
            app.speakerradiusmEditField.Value = 0.005;
            app.speakersspreadmLabel = uilabel(app.SettingsTab);
            app.speakersspreadmLabel.Text = 'speakers spread, m';
            app.speakersspreadXEditField = uieditfield(app.SettingsTab, 'numeric');
            app.speakersspreadXEditField.Limits = [0 Inf];
            app.speakersspreadXEditField.ValueChangedFcn = createCallbackFcn(app, @speakersspreadXEditFieldValueChanged, true);
            app.speakersspreadXEditField.Value = 0.002;
            app.signalfrequencyHzLabel = uilabel(app.SettingsTab);
            app.signalfrequencyHzLabel.Text = 'signal frequency, Hz';
            app.signalfrequencyHzEditField = uieditfield(app.SettingsTab, 'numeric');
            app.signalfrequencyHzEditField.Limits = [1 Inf];
            app.signalfrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.signalfrequencyHzEditField.Value = 40000;
            app.signalamplitudeEditFieldLabel = uilabel(app.SettingsTab);
            app.signalamplitudeEditFieldLabel.Text = 'signal amplitude';
            app.signalamplitudeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.signalamplitudeEditField.Limits = [0 Inf];
            app.signalamplitudeEditField.Value = 0.02;
            app.topbottomdistancemLabel = uilabel(app.SettingsTab);
            app.topbottomdistancemLabel.Text = 'top-bottom dist., m';
            app.topbottomdistmEditField = uieditfield(app.SettingsTab, 'numeric');
            app.topbottomdistmEditField.Limits = [0.001 Inf];
            app.topbottomdistmEditField.ValueChangedFcn = createCallbackFcn(app, @topbottomdistmEditFieldValueChanged, true);
            app.topbottomdistmEditField.Value = 0.24;
            app.additionalvisibilityborderLabel = uilabel(app.SettingsTab);
            app.additionalvisibilityborderLabel.Text = {' additional visibility '; 'border, m'};
            app.additionalvisibilitybordermEditField = uieditfield(app.SettingsTab, 'numeric');
            app.additionalvisibilitybordermEditField.Limits = [0 Inf];
            app.additionalvisibilitybordermEditField.Value = 0.01;
            app.numberofspeakersYEditField = uieditfield(app.SettingsTab, 'numeric');
            app.numberofspeakersYEditField.Limits = [1 100];
            app.numberofspeakersYEditField.ValueChangedFcn = createCallbackFcn(app, @numberofspeakersYEditFieldValueChanged, true);
            app.numberofspeakersYEditField.Value = 10;
            app.speakersspreadYEditField = uieditfield(app.SettingsTab, 'numeric');
            app.speakersspreadYEditField.Limits = [0 Inf];
            app.speakersspreadYEditField.ValueChangedFcn = createCallbackFcn(app, @speakersspreadYEditFieldValueChanged, true);
            app.speakersspreadYEditField.Value = 0.002;
            app.XLabel = uilabel(app.SettingsTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.SettingsTab);
            app.YLabel.Text = 'Y';
            app.Label = uilabel(app.SettingsTab);
            app.Label.Text = '';
            app.FocuspointcoordinatesDropDownLabel = uilabel(app.SettingsTab);
            app.FocuspointcoordinatesDropDownLabel.Text = 'Focus point coordinates:';
            app.FocuspointcoordinatesDropDown = uidropdown(app.SettingsTab);
            app.FocuspointcoordinatesDropDown.Items = {'%', 'm'};
            app.FocuspointcoordinatesDropDown.ValueChangedFcn = createCallbackFcn(app, @FocuspointcoordinatesDropDownValueChanged, true);
            app.FocuspointcoordinatesDropDown.Value = '%';
            app.XEditFieldLabel = uilabel(app.SettingsTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.SettingsTab, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Value = 50;
            app.YEditFieldLabel = uilabel(app.SettingsTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.SettingsTab, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Value = 50;
            app.ZEditFieldLabel = uilabel(app.SettingsTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ZEditField.Value = 50;
            app.XaxisSliderLabel = uilabel(app.SettingsTab);
            app.XaxisSliderLabel.Text = 'X axis';
            app.XaxisSlider = uislider(app.SettingsTab);
            app.XaxisSlider.Limits = [20 200];
            app.XaxisSlider.ValueChangedFcn = createCallbackFcn(app, @XaxisSliderValueChanged, true);
            app.XaxisSlider.Value = 50;
            app.YaxisSliderLabel = uilabel(app.SettingsTab);
            app.YaxisSliderLabel.Text = 'Y axis';
            app.YaxisSlider = uislider(app.SettingsTab);
            app.YaxisSlider.Limits = [20 200];
            app.YaxisSlider.ValueChangedFcn = createCallbackFcn(app, @YaxisSliderValueChanged, true);
            app.YaxisSlider.Value = 50;
            app.ZaxisSliderLabel = uilabel(app.SettingsTab);
            app.ZaxisSliderLabel.Text = 'Z axis';
            app.ZaxisSlider = uislider(app.SettingsTab);
            app.ZaxisSlider.Limits = [20 200];
            app.ZaxisSlider.Value = 100;
            app.ResolutionnumberofpointsgeneratedLabel = uilabel(app.SettingsTab);
            app.ResolutionnumberofpointsgeneratedLabel.Text = 'Resolution (number of points generated):';
            app.RestoredefaultsettingsButton = uibutton(app.SettingsTab, 'push');
            app.RestoredefaultsettingsButton.Text = 'Restore default settings';
            app.DirectivitypatternButtonGroup = uibuttongroup(app.SettingsTab);
            app.DirectivitypatternButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DirectivitypatternButtonGroupSelectionChanged, true);
            app.DirectivitypatternButtonGroup.Title = 'Directivity pattern';
            app.OMNIButton = uiradiobutton(app.DirectivitypatternButtonGroup);
            app.OMNIButton.Text = 'OMNI';
            app.OMNIButton.Value = true;
            app.CPRBButton = uiradiobutton(app.DirectivitypatternButtonGroup);
            app.CPRBButton.Text = 'CPRB';
            app.VisualisemodelCheckBox = uicheckbox(app.SettingsTab);
            app.VisualisemodelCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisualisemodelCheckBoxValueChanged, true);
            app.VisualisemodelCheckBox.Text = 'Visualise model';
            app.DataplotsTab = uitab(app.TabGroup);
            app.DataplotsTab.Title = 'Data plots';
            app.UIAxes_5 = uiaxes(app.DataplotsTab);
            title(app.UIAxes_5, 'Acoustic pressure 3D')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_7 = uiaxes(app.DataplotsTab);
            title(app.UIAxes_7, 'Acoustic pressure - Z values')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes = uiaxes(app.DataplotsTab);
            title(app.UIAxes, 'Phases of bottom array')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_10 = uiaxes(app.DataplotsTab);
            title(app.UIAxes_10, 'Acoustic pressure - Y values')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_9 = uiaxes(app.DataplotsTab);
            title(app.UIAxes_9, 'Phases of top array')
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_2 = uiaxes(app.DataplotsTab);
            title(app.UIAxes_2, 'Acoustic pressure - X values')
            zlabel(app.UIAxes_2, 'Z')
            app.dimentionalaccousticpressuredisplaypresentingLabel = uilabel(app.DataplotsTab);
            app.dimentionalaccousticpressuredisplaypresentingLabel.Text = 'Three dimentional acoustic pressure display, presenting pressure data in between bottom and top array of speakers, extended horizontally by value of  ''additional visibility border''.';
            app.Label_2 = uilabel(app.DataplotsTab);
            app.Label_2.Text = {'Graphs presenting phases, in which speakers in given model have to work in order'; 'to generate sound waves focused in given point'};
            app.AccousticpressureLabel = uilabel(app.DataplotsTab);
            app.AccousticpressureLabel.Text = 'Acoustic pressure distribution at the coordinates of focus point, presented in 2D graphs.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pressure_displayer_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end